classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        SpecificAngleEditField         matlab.ui.control.NumericEditField
        FilterButtonGroup              matlab.ui.container.ButtonGroup
        BartlettHannButton             matlab.ui.control.RadioButton
        HammingButton                  matlab.ui.control.RadioButton
        RamLakButton                   matlab.ui.control.RadioButton
        NoneButton                     matlab.ui.control.RadioButton
        SpecificAngleEditFieldLabel    matlab.ui.control.Label
        BACKPROJECTButton              matlab.ui.control.Button
        PROJECTButton                  matlab.ui.control.Button
        PixelDimensionforReconstructedSquareImageEditField  matlab.ui.control.NumericEditField
        PixelDimensionforReconstructedSquareImageLabel  matlab.ui.control.Label
        AngleStepSizeindegreesEditField  matlab.ui.control.NumericEditField
        AngleStepSizeindegreesEditFieldLabel  matlab.ui.control.Label
        NumberofBeamsEditField         matlab.ui.control.NumericEditField
        NumberofBeamsEditFieldLabel    matlab.ui.control.Label
        PhantomFilePathEditField       matlab.ui.control.EditField
        PhantomFilePathEditFieldLabel  matlab.ui.control.Label
        UIAxes4                        matlab.ui.control.UIAxes
        UIAxes3                        matlab.ui.control.UIAxes
        UIAxes2                        matlab.ui.control.UIAxes
        UIAxes                         matlab.ui.control.UIAxes
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PROJECTButton
        function PROJECTButtonPushed(app, event)
            I1 = load(app.PhantomFilePathEditField.Value);
            I1 = struct2array(I1);
            P1 = radon_project(I1,app.NumberofBeamsEditField.Value,app.AngleStepSizeindegreesEditField.Value);
            imagesc(I1,'Parent',app.UIAxes); colormap(app.UIAxes,gray);
            xlim(app.UIAxes,[0, size(I1,2)]);
            ylim(app.UIAxes,[0, size(I1,1)]);
            angle = app.SpecificAngleEditField.Value;
            idx = round(angle / (app.AngleStepSizeindegreesEditField.Value));
            plot(1:length(P1(:,idx)),(P1(:,idx)),'Parent',app.UIAxes3)
            colormap(app.UIAxes3,gray)
        end

        % Button down function: UIAxes
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: BACKPROJECTButton
        function BACKPROJECTButtonPushed(app, event)
            I1 = load(app.PhantomFilePathEditField.Value);
            I1 = struct2array(I1);
            P1 = transpose(radon(I1));
            Nrows = app.PixelDimensionforReconstructedSquareImageEditField.Value;
            R1 = real(back_projection(Nrows,Nrows,P1));
            imagesc(R1,'Parent',app.UIAxes4); 
            xlim(app.UIAxes4,[0, Nrows]);
            ylim(app.UIAxes4,[0, Nrows]);
            colormap(app.UIAxes4,gray)
            colormap(app.UIAxes2,gray)
            button = app.FilterButtonGroup.SelectedObject;
            switch button.Text
                case 'None'
                    R1 = real(back_projection(Nrows,Nrows,P1));
                case 'Ram-Lak'
                    R1 = real(filtered_back_projection_ram(Nrows,Nrows,P1));
                case 'Bartlett-Hann'
                    R1 = real(filtered_back_projection_bart(Nrows,Nrows,P1));
                case 'Hamming'
                    R1 = real(filtered_back_projection_hamm(Nrows,Nrows,P1));
            end
            imagesc(R1,'Parent',app.UIAxes2); 
            xlim(app.UIAxes2,[0, Nrows]);
            ylim(app.UIAxes2,[0, Nrows]);
            colormap(app.UIAxes2,gray)

        end

        % Selection changed function: FilterButtonGroup
        function FilterButtonGroupSelectionChanged(app, event)
            
            selectedButton = app.FilterButtonGroup.SelectedObject;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Original')
            xlabel(app.UIAxes, 'Angle')
            ylabel(app.UIAxes, 'Beams')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIAxesButtonDown, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Filtered-Projection')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes3 = uiaxes(app.UIFigure);
            title(app.UIAxes3, 'Projection for Specific Angle')
            xlabel(app.UIAxes3, 'Beams')
            ylabel(app.UIAxes3, 'Projections')
            zlabel(app.UIAxes3, 'Z')
            app.UIAxes4 = uiaxes(app.UIFigure);
            title(app.UIAxes4, 'Back Projection')
            zlabel(app.UIAxes4, 'Z')
            app.PhantomFilePathEditFieldLabel = uilabel(app.UIFigure);
            app.PhantomFilePathEditFieldLabel.Text = 'Phantom File Path';
            app.PhantomFilePathEditField = uieditfield(app.UIFigure, 'text');
            app.NumberofBeamsEditFieldLabel = uilabel(app.UIFigure);
            app.NumberofBeamsEditFieldLabel.Text = 'Number of Beams';
            app.NumberofBeamsEditField = uieditfield(app.UIFigure, 'numeric');
            app.AngleStepSizeindegreesEditFieldLabel = uilabel(app.UIFigure);
            app.AngleStepSizeindegreesEditFieldLabel.Text = 'Angle Step Size (in degrees)';
            app.AngleStepSizeindegreesEditField = uieditfield(app.UIFigure, 'numeric');
            app.PixelDimensionforReconstructedSquareImageLabel = uilabel(app.UIFigure);
            app.PixelDimensionforReconstructedSquareImageLabel.Text = {'Pixel Dimension for       '; 'Reconstructed Square Image'};
            app.PixelDimensionforReconstructedSquareImageEditField = uieditfield(app.UIFigure, 'numeric');
            app.PROJECTButton = uibutton(app.UIFigure, 'push');
            app.PROJECTButton.ButtonPushedFcn = createCallbackFcn(app, @PROJECTButtonPushed, true);
            app.PROJECTButton.Text = 'PROJECT';
            app.BACKPROJECTButton = uibutton(app.UIFigure, 'push');
            app.BACKPROJECTButton.ButtonPushedFcn = createCallbackFcn(app, @BACKPROJECTButtonPushed, true);
            app.BACKPROJECTButton.Text = 'BACK PROJECT';
            app.SpecificAngleEditFieldLabel = uilabel(app.UIFigure);
            app.SpecificAngleEditFieldLabel.Text = 'Specific  Angle ';
            app.FilterButtonGroup = uibuttongroup(app.UIFigure);
            app.FilterButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @FilterButtonGroupSelectionChanged, true);
            app.FilterButtonGroup.Title = 'Filter';
            app.NoneButton = uiradiobutton(app.FilterButtonGroup);
            app.NoneButton.Text = 'None';
            app.NoneButton.Value = true;
            app.RamLakButton = uiradiobutton(app.FilterButtonGroup);
            app.RamLakButton.Text = 'Ram-Lak';
            app.HammingButton = uiradiobutton(app.FilterButtonGroup);
            app.HammingButton.Text = 'Hamming';
            app.BartlettHannButton = uiradiobutton(app.FilterButtonGroup);
            app.BartlettHannButton.Text = 'Bartlett-Hann';
            app.SpecificAngleEditField = uieditfield(app.UIFigure, 'numeric');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end